classdef app_projet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        mainTab                         matlab.ui.container.Tab
        QuitterButton                   matlab.ui.control.Button
        MST2ISERTLabel_2                matlab.ui.control.Label
        ConceptionduneapplicationdetraitementdimagesavecMatlabLabel  matlab.ui.control.Label
        PrIELMOURABITLabel              matlab.ui.control.Label
        DemandparLabel                  matlab.ui.control.Label
        SuivantButton                   matlab.ui.control.Button
        Anneuniversitaire20242025Label  matlab.ui.control.Label
        HamzaCHEHAIBILabel              matlab.ui.control.Label
        RaliseparLabel                  matlab.ui.control.Label
        DpartementGnielectriqueLabel    matlab.ui.control.Label
        logo                            matlab.ui.control.Image
        V1Tab                           matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        ImporterButton                  matlab.ui.control.Button
        RedimensionnerButton            matlab.ui.control.Button
        TournerButton                   matlab.ui.control.Button
        DonnelecoefdemiselechelleEditField  matlab.ui.control.NumericEditField
        DonnelecoefdemiselechelleEditFieldLabel  matlab.ui.control.Label
        DonnelanglederotationEditField  matlab.ui.control.NumericEditField
        DonnelanglederotationEditFieldLabel  matlab.ui.control.Label
        msgImgImporter                  matlab.ui.control.EditField
        HomeButton_2                    matlab.ui.control.Button
        SuivantButton_2                 matlab.ui.control.Button
        load_image                      matlab.ui.control.UIAxes
        affiche_image                   matlab.ui.control.UIAxes
        V2Tab                           matlab.ui.container.Tab
        SaveButton_2                    matlab.ui.control.Button
        msgImgImporter_2                matlab.ui.control.EditField
        InitialiserlimageButton         matlab.ui.control.Button
        EgaliserlimageButton            matlab.ui.control.Button
        GammaSlider                     matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        HistogrammenormaliscumulButton  matlab.ui.control.Button
        HistogrammeButton               matlab.ui.control.Button
        ContrasteSlider                 matlab.ui.control.Slider
        ContrasteSliderLabel            matlab.ui.control.Label
        BrillanceSlider                 matlab.ui.control.Slider
        BrillanceSliderLabel            matlab.ui.control.Label
        HomeButton                      matlab.ui.control.Button
        ImporterButton_2                matlab.ui.control.Button
        PrcdenteButton                  matlab.ui.control.Button
        SuivantButton_3                 matlab.ui.control.Button
        affiche_image_2                 matlab.ui.control.UIAxes
        load_image_2                    matlab.ui.control.UIAxes
        V3Tab                           matlab.ui.container.Tab
        AppliquerButton                 matlab.ui.control.Button
        OrdredufiltreEditField          matlab.ui.control.NumericEditField
        OrdredufiltreEditFieldLabel     matlab.ui.control.Label
        FiltredordrekLabel              matlab.ui.control.Label
        FentredoittreimpairEditField    matlab.ui.control.NumericEditField
        FentredoittreimpairEditFieldLabel  matlab.ui.control.Label
        SaveButton_3                    matlab.ui.control.Button
        msgImgImporter_3                matlab.ui.control.EditField
        DetectiondecontourButtonGroup   matlab.ui.container.ButtonGroup
        RobertsButton                   matlab.ui.control.RadioButton
        SobelButton                     matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        CannyButton                     matlab.ui.control.RadioButton
        SuppressiondebruitdoublecliquezpourchoisirListBox  matlab.ui.control.ListBox
        SuppressiondebruitdoublecliquezpourchoisirListBoxLabel  matlab.ui.control.Label
        VariancedebruitEditField        matlab.ui.control.NumericEditField
        VariancedebruitEditField_2Label  matlab.ui.control.Label
        TypedebruitEditField            matlab.ui.control.EditField
        TypedebruitEditField_2Label     matlab.ui.control.Label
        AfficherlimagebruiteButton      matlab.ui.control.Button
        HomeButton_3                    matlab.ui.control.Button
        ImporterButton_3                matlab.ui.control.Button
        PrcdenteButton_2                matlab.ui.control.Button
        affiche_image_3                 matlab.ui.control.UIAxes
        load_image_3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image; % Description
        imgBr ;
        imageB ;
        imageC;
        image_V1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            app.TabGroup.Visible;
            app.TabGroup.SelectedTab = app.V1Tab;
        end

        % Button pushed function: HomeButton_2
        function HomeButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Button pushed function: SuivantButton_2
        function SuivantButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.V2Tab;
        end

        % Button pushed function: ImporterButton_2
        function ImporterButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Sélectionnez une image');
    
            if isequal(filename, 0)
                app.msgImgImporter_2.Value = 'Aucune image importée.';
                return;
            end
            
            try
                fullpathname = fullfile(pathname, filename);
                app.image = imread(fullpathname);
                imshow(app.image, 'Parent', app.load_image_2);
  
                app.msgImgImporter_2.Value = sprintf('Image "%s" importée avec succès.', filename);
            catch
                app.msgImgImporter_2.Value = 'Erreur lors de l''importation de l''image.';
            end            
            app.imageB = app.image; 
            app.imageC = app.image; 
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Button pushed function: PrcdenteButton
        function PrcdenteButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.V1Tab;
        end

        % Button pushed function: SuivantButton_3
        function SuivantButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.V3Tab;
        end

        % Button pushed function: HistogrammeButton
        function HistogrammeButtonPushed(app, event)
            h = hsto(app.image);
            k = 0:255;
            cla(app.affiche_image_2,'reset');
            bar(app.affiche_image_2,k,h);
        end

        % Value changed function: BrillanceSlider
        function BrillanceSliderValueChanged(app, event)
            value = app.BrillanceSlider.Value;
            app.imageB = ajuster_brillance(app.imageC,value);
            imshow(app.imageB,'Parent',app.affiche_image_2);
        end

        % Value changed function: ContrasteSlider
        function ContrasteSliderValueChanged(app, event)
            value = app.ContrasteSlider.Value;
            app.imageC = ajuster_contraste(app.imageB,value);
            imshow(app.imageC,'Parent',app.affiche_image_2);
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
            value = app.GammaSlider.Value;
            img = correction_gamma(app.image,value);
            imshow(img,'Parent',app.affiche_image_2);
        end

        % Button pushed function: HistogrammenormaliscumulButton
        function HistogrammenormaliscumulButtonPushed(app, event)
            h = hstoNC(app.image);
            k = 0:255;       
            cla(app.affiche_image_2,'reset');
            bar(app.affiche_image_2,k,h);              
        end

        % Button pushed function: ImporterButton_3
        function ImporterButton_3Pushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Sélectionnez une image');
    
            if isequal(filename, 0)
                app.msgImgImporter_3.Value = 'Aucune image importée.';
                return;
            end
            
            try
                fullpathname = fullfile(pathname, filename);
                app.image = imread(fullpathname);
                imshow(app.image, 'Parent', app.load_image_3);
                app.UIFigure.Visible = 'on'; 
                app.UIFigure.WindowState = 'normal';
  
                app.msgImgImporter_3.Value = sprintf('Image "%s" importée avec succès.', filename);
            catch
                app.msgImgImporter_3.Value = 'Erreur lors de l''importation de l''image.';
            end            
        end

        % Button pushed function: AfficherlimagebruiteButton
        function AfficherlimagebruiteButtonPushed(app, event)
            value = app.TypedebruitEditField.Value;
            variance = app.VariancedebruitEditField.Value;
            if strcmp(value,("gaussian"))
                app.imgBr = imnoise(app.image,"gaussian",variance);
            elseif strcmp(value,("salt & pepper"))
                app.imgBr = imnoise(app.image,"salt & pepper",variance);
            elseif strcmp(value,("poisson"))
                app.imgBr = imnoise(app.image,"poisson");
            elseif strcmp(value,("speckle"))
                app.imgBr = imnoise(app.image,"speckle",variance);
            else 
                app.imgBr = app.image;
            end
            imshow(app.imgBr,'Parent',app.affiche_image_3);
        end

        % Double-clicked callback: 
        % SuppressiondebruitdoublecliquezpourchoisirListBox
        function SuppressiondebruitdoublecliquezpourchoisirListBoxDoubleClicked(app, event)
            if isempty(app.imgBr)
                app.msgImgImporter_3.Value = "L'image n'a pas été bruitée. Veuillez ajouter un bruit avant de filtrer.";
                return;
            end
            choix = app.SuppressiondebruitdoublecliquezpourchoisirListBox.Value;
            switch choix
                case "Median 3*3"
                    img = medianeFiltre(app.imgBr,3);
                case "Median 5*5"
                    img = medianeFiltre(app.imgBr,5);
                case "Median 7*7"
                    img = medianeFiltre(app.imgBr,7);
                case "Moyenneur 3*3"
                    img = filtreMoyenneur(app.imgBr,3);
                case "Moyenneur 5*5"
                    img = filtreMoyenneur(app.imgBr,5);
                case "Moyenneur 7*7"
                    img = filtreMoyenneur(app.imgBr,7);
            end
            imshow(img,'Parent',app.affiche_image_3);
        end

        % Button pushed function: QuitterButton
        function QuitterButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, ...
                'Êtes-vous sûr de vouloir quitter l''application ?', ...
                'Confirmation', ...
                'Options', {'Oui', 'Non'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            
            if strcmp(choice, 'Oui')
                delete(app);
            end
        end

        % Selection changed function: DetectiondecontourButtonGroup
        function DetectiondecontourButtonGroupSelectionChanged(app, event)
            selectedButton = app.DetectiondecontourButtonGroup.SelectedObject;
%             gray_image = rgbtogray(app.image);
            if selectedButton == app.CannyButton
                c=Mycanny(app.image);
            elseif selectedButton == app.PrewittButton
                c = detecter_contour(app.image,'Prewitt');
            elseif selectedButton == app.SobelButton
                c = detecter_contour(app.image,'Sobel');
            elseif selectedButton == app.RobertsButton
                c = detecter_contour(app.image,'Roberts');
            end  
                imshow(c,'Parent', app.affiche_image_3);
        end

        % Button pushed function: EgaliserlimageButton
        function EgaliserlimageButtonPushed(app, event)
            img = egalisation(app.image);
            imshow(img,'Parent', app.affiche_image_2);
        end

        % Button pushed function: InitialiserlimageButton
        function InitialiserlimageButtonPushed(app, event)
            app.imageB = app.image;
            app.imageC = app.image; 
        end

        % Button pushed function: HomeButton_3
        function HomeButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Button pushed function: PrcdenteButton_2
        function PrcdenteButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.V2Tab;
        end

        % Button pushed function: TournerButton
        function TournerButtonPushed(app, event)
            angle = app.DonnelanglederotationEditField.Value;
            app.image_V1 = rotation_image(app.image_V1,angle);
            cla(app.affiche_image,"reset");
            imshow(app.image_V1,'Parent',app.affiche_image);
            [n,m,~]=size(app.image_V1);
            app.msgImgImporter.Value = sprintf("La nouvelle taille de l'image après rotation de %d° est : %dx%d", angle, n, m);
        end

        % Button pushed function: RedimensionnerButton
        function RedimensionnerButtonPushed(app, event)
            coef = app.DonnelecoefdemiselechelleEditField.Value;
            app.image_V1 = mise_echelle(app.image_V1,coef);
            cla(app.affiche_image,"reset");
            imshow(app.image_V1,'Parent',app.affiche_image);
            [n,m,~]=size(app.image_V1);
            app.msgImgImporter.Value = sprintf("La nouvelle taille de l'image après la mise à l'échelle par %d est : %dx%d", coef, n, m);
        end

        % Button pushed function: ImporterButton
        function ImporterButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Sélectionnez une image');
            if isequal(filename, 0)
                app.msgImgImporter.Value = 'Aucune image importée.';
                return;
            end
        
            try
                fullpathname = fullfile(pathname, filename);
                app.image = imread(fullpathname);
                imshow(app.image, 'Parent', app.load_image);
                [n, m, k] = size(app.image);        
                if k == 3
                    gr_img = rgb2gray(app.image);
                else
                    gr_img = app.image;
                end
        
                imshow(gr_img, 'Parent', app.affiche_image);
                fileInfo = dir(fullpathname);
                toctets = fileInfo.bytes;
                app.msgImgImporter.Value = sprintf('Image "%s" de taille %dx%d, %d octets importée avec succès.', filename, n, m, toctets);
            catch ME
                app.msgImgImporter.Value = sprintf('Erreur lors de l''importation de l''image : %s', ME.message);
            end
            app.image_V1 = app.image;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            img = getimage(app.affiche_image);
            if isempty(img)
                app.msgImgImporter.Value = 'Aucune image à sauvegarder.';
                return;
            end

            [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'}, 'Enregistrer l''image');
            if isequal(file, 0) || isequal(path, 0)
                app.msgImgImporter.Value = 'Enregistrement annulé.';
                return;
            end
        
            fullpath = fullfile(path, file);
            
            try
                imwrite(img, fullpath);
                app.msgImgImporter.Value = sprintf('Image enregistrée avec succès sous "%s".', fullpath);
            catch
                app.msgImgImporter.Value = 'Erreur lors de l''enregistrement de l''image.';
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            img = getimage(app.affiche_image_2);
            if isempty(img)
                app.msgImgImporter_2.Value = 'Aucune image à sauvegarder.';
                return;
            end

            [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'}, 'Enregistrer l''image');
            if isequal(file, 0) || isequal(path, 0)
                app.msgImgImporter_2.Value = 'Enregistrement annulé.';
                return;
            end
        
            fullpath = fullfile(path, file);
            
            try
                imwrite(img, fullpath);
                app.msgImgImporter_2.Value = sprintf('Image enregistrée avec succès sous "%s".', fullpath);
            catch
                app.msgImgImporter_2.Value = 'Erreur lors de l''enregistrement de l''image.';
            end
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            img = getimage(app.affiche_image_3);
            if isempty(img)
                app.msgImgImporter_3.Value = 'Aucune image à sauvegarder.';
                return;
            end

            [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'}, 'Enregistrer l''image');
            if isequal(file, 0) || isequal(path, 0)
                app.msgImgImporter_3.Value = 'Enregistrement annulé.';
                return;
            end
        
            fullpath = fullfile(path, file);
            
            try
                imwrite(img, fullpath);
                app.msgImgImporter_3.Value = sprintf('Image enregistrée avec succès sous "%s".', fullpath);
            catch
                app.msgImgImporter_3.Value = 'Erreur lors de l''enregistrement de l''image.';
            end
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            c = app.FentredoittreimpairEditField.Value;
            o = app.OrdredufiltreEditField.Value;
  
            if mod(c, 2) == 0
                app.msgImgImporter_3.Value = "Erreur ! La taille de la fenêtre (c) doit être un entier impair.";
                return;
            end
        
            if o < 1 || o > c^2
                app.msgImgImporter_3.Value = sprintf("Erreur ! L'ordre du filtre doit être compris entre %d et %d.", 1, c^2);
                return;
            end
        
            if isempty(app.imgBr)
                app.imgBr = app.image;
            end
        
            try
                img = filtreDordre(app.imgBr, c, o);
                imshow(img, 'Parent', app.affiche_image_3);
                app.msgImgImporter_3.Value = "Filtre appliqué avec succès !";
            catch 
                app.msgImgImporter_3.Value = "Erreur lors de l'application du filtre ";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.mainTab = uitab(app.TabGroup);
            app.mainTab.Title = 'main';
            app.logo = uiimage(app.mainTab);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'logoFstm.png');
            app.DpartementGnielectriqueLabel = uilabel(app.mainTab);
            app.DpartementGnielectriqueLabel.Text = 'Département Génie Électrique';
            app.RaliseparLabel = uilabel(app.mainTab);
            app.RaliseparLabel.Text = 'Réalise par : ';
            app.HamzaCHEHAIBILabel = uilabel(app.mainTab);
            app.HamzaCHEHAIBILabel.Text = 'Hamza CHEHAIBI';
            app.Anneuniversitaire20242025Label = uilabel(app.mainTab);
            app.Anneuniversitaire20242025Label.Text = 'Année universitaire 2024/2025';
            app.SuivantButton = uibutton(app.mainTab, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Icon = fullfile(pathToMLAPP, 'suivIcon.png');
            app.SuivantButton.Text = 'Suivant';
            app.DemandparLabel = uilabel(app.mainTab);
            app.DemandparLabel.Text = 'Demandé par : ';
            app.PrIELMOURABITLabel = uilabel(app.mainTab);
            app.PrIELMOURABITLabel.Text = 'Pr. : I. EL MOURABIT ';
            app.ConceptionduneapplicationdetraitementdimagesavecMatlabLabel = uilabel(app.mainTab);
            app.ConceptionduneapplicationdetraitementdimagesavecMatlabLabel.Text = {'Conception d’une application de traitement d’images '; 'avec Matlab '};
            app.MST2ISERTLabel_2 = uilabel(app.mainTab);
            app.MST2ISERTLabel_2.Text = 'MST2 ISERT';
            app.QuitterButton = uibutton(app.mainTab, 'push');
            app.QuitterButton.ButtonPushedFcn = createCallbackFcn(app, @QuitterButtonPushed, true);
            app.QuitterButton.Icon = fullfile(pathToMLAPP, 'Design sans titre.png');
            app.QuitterButton.Text = 'Quitter';
            app.V1Tab = uitab(app.TabGroup);
            app.V1Tab.Title = 'V1';
            app.affiche_image = uiaxes(app.V1Tab);
            title(app.affiche_image, 'Image resultat')
            app.affiche_image.XTick = [];
            app.affiche_image.YTick = [];
            app.load_image = uiaxes(app.V1Tab);
            title(app.load_image, 'Image originale')
            app.load_image.XTick = [];
            app.load_image.YTick = [];
            app.SuivantButton_2 = uibutton(app.V1Tab, 'push');
            app.SuivantButton_2.ButtonPushedFcn = createCallbackFcn(app, @SuivantButton_2Pushed, true);
            app.SuivantButton_2.Icon = fullfile(pathToMLAPP, 'suivIcon.png');
            app.SuivantButton_2.Text = 'Suivant';
            app.HomeButton_2 = uibutton(app.V1Tab, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_2Pushed, true);
            app.HomeButton_2.Icon = fullfile(pathToMLAPP, 'Design sans titre (1).png');
            app.HomeButton_2.Text = 'Home';
            app.msgImgImporter = uieditfield(app.V1Tab, 'text');
            app.DonnelanglederotationEditFieldLabel = uilabel(app.V1Tab);
            app.DonnelanglederotationEditFieldLabel.Text = 'Donne l''angle de rotation';
            app.DonnelanglederotationEditField = uieditfield(app.V1Tab, 'numeric');
            app.DonnelecoefdemiselechelleEditFieldLabel = uilabel(app.V1Tab);
            app.DonnelecoefdemiselechelleEditFieldLabel.Text = 'Donne le coef de mise à l''echelle';
            app.DonnelecoefdemiselechelleEditField = uieditfield(app.V1Tab, 'numeric');
            app.TournerButton = uibutton(app.V1Tab, 'push');
            app.TournerButton.ButtonPushedFcn = createCallbackFcn(app, @TournerButtonPushed, true);
            app.TournerButton.Text = 'Tourner';
            app.RedimensionnerButton = uibutton(app.V1Tab, 'push');
            app.RedimensionnerButton.ButtonPushedFcn = createCallbackFcn(app, @RedimensionnerButtonPushed, true);
            app.RedimensionnerButton.Text = 'Redimensionner';
            app.ImporterButton = uibutton(app.V1Tab, 'push');
            app.ImporterButton.ButtonPushedFcn = createCallbackFcn(app, @ImporterButtonPushed, true);
            app.ImporterButton.Icon = fullfile(pathToMLAPP, 'Design sans titre (2).png');
            app.ImporterButton.Text = 'Importer';
            app.SaveButton = uibutton(app.V1Tab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'saveIcon.png');
            app.SaveButton.Text = '';
            app.V2Tab = uitab(app.TabGroup);
            app.V2Tab.Title = 'V2';
            app.load_image_2 = uiaxes(app.V2Tab);
            title(app.load_image_2, 'Image originale')
            app.load_image_2.XTick = [];
            app.load_image_2.YTick = [];
            app.affiche_image_2 = uiaxes(app.V2Tab);
            title(app.affiche_image_2, 'Image resultat')
            app.affiche_image_2.XTick = [];
            app.affiche_image_2.YTick = [];
            app.SuivantButton_3 = uibutton(app.V2Tab, 'push');
            app.SuivantButton_3.ButtonPushedFcn = createCallbackFcn(app, @SuivantButton_3Pushed, true);
            app.SuivantButton_3.Icon = fullfile(pathToMLAPP, 'suivIcon.png');
            app.SuivantButton_3.Text = 'Suivant';
            app.PrcdenteButton = uibutton(app.V2Tab, 'push');
            app.PrcdenteButton.ButtonPushedFcn = createCallbackFcn(app, @PrcdenteButtonPushed, true);
            app.PrcdenteButton.Icon = fullfile(pathToMLAPP, 'precIcon.png');
            app.PrcdenteButton.Text = 'Précédente';
            app.ImporterButton_2 = uibutton(app.V2Tab, 'push');
            app.ImporterButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImporterButton_2Pushed, true);
            app.ImporterButton_2.Icon = fullfile(pathToMLAPP, 'Design sans titre (2).png');
            app.ImporterButton_2.Text = 'Importer';
            app.HomeButton = uibutton(app.V2Tab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = fullfile(pathToMLAPP, 'Design sans titre (1).png');
            app.HomeButton.Text = 'Home';
            app.BrillanceSliderLabel = uilabel(app.V2Tab);
            app.BrillanceSliderLabel.Text = 'Brillance';
            app.BrillanceSlider = uislider(app.V2Tab);
            app.BrillanceSlider.Limits = [-100 100];
            app.BrillanceSlider.ValueChangedFcn = createCallbackFcn(app, @BrillanceSliderValueChanged, true);
            app.ContrasteSliderLabel = uilabel(app.V2Tab);
            app.ContrasteSliderLabel.Text = 'Contraste';
            app.ContrasteSlider = uislider(app.V2Tab);
            app.ContrasteSlider.Limits = [-10 10];
            app.ContrasteSlider.ValueChangedFcn = createCallbackFcn(app, @ContrasteSliderValueChanged, true);
            app.HistogrammeButton = uibutton(app.V2Tab, 'push');
            app.HistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeButtonPushed, true);
            app.HistogrammeButton.Text = 'Histogramme';
            app.HistogrammenormaliscumulButton = uibutton(app.V2Tab, 'push');
            app.HistogrammenormaliscumulButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammenormaliscumulButtonPushed, true);
            app.HistogrammenormaliscumulButton.Text = 'Histogramme normalisé cumulé';
            app.GammaSliderLabel = uilabel(app.V2Tab);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.V2Tab);
            app.GammaSlider.Limits = [0 5];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.EgaliserlimageButton = uibutton(app.V2Tab, 'push');
            app.EgaliserlimageButton.ButtonPushedFcn = createCallbackFcn(app, @EgaliserlimageButtonPushed, true);
            app.EgaliserlimageButton.Text = 'Egaliser l''image';
            app.InitialiserlimageButton = uibutton(app.V2Tab, 'push');
            app.InitialiserlimageButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiserlimageButtonPushed, true);
            app.InitialiserlimageButton.Text = 'Initialiser l''image';
            app.msgImgImporter_2 = uieditfield(app.V2Tab, 'text');
            app.SaveButton_2 = uibutton(app.V2Tab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Icon = fullfile(pathToMLAPP, 'saveIcon.png');
            app.SaveButton_2.Text = '';
            app.V3Tab = uitab(app.TabGroup);
            app.V3Tab.Title = 'V3';
            app.load_image_3 = uiaxes(app.V3Tab);
            title(app.load_image_3, 'Image originale')
            app.load_image_3.XTick = [];
            app.load_image_3.YTick = [];
            app.affiche_image_3 = uiaxes(app.V3Tab);
            title(app.affiche_image_3, 'Image resultat')
            app.affiche_image_3.XTick = [];
            app.affiche_image_3.YTick = [];
            app.affiche_image_3.YTickLabel = '';
            app.PrcdenteButton_2 = uibutton(app.V3Tab, 'push');
            app.PrcdenteButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrcdenteButton_2Pushed, true);
            app.PrcdenteButton_2.Icon = fullfile(pathToMLAPP, 'precIcon.png');
            app.PrcdenteButton_2.Text = 'Précédente';
            app.ImporterButton_3 = uibutton(app.V3Tab, 'push');
            app.ImporterButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImporterButton_3Pushed, true);
            app.ImporterButton_3.Icon = fullfile(pathToMLAPP, 'Design sans titre (2).png');
            app.ImporterButton_3.Text = 'Importer';
            app.HomeButton_3 = uibutton(app.V3Tab, 'push');
            app.HomeButton_3.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_3Pushed, true);
            app.HomeButton_3.Icon = fullfile(pathToMLAPP, 'Design sans titre (1).png');
            app.HomeButton_3.Text = 'Home';
            app.AfficherlimagebruiteButton = uibutton(app.V3Tab, 'push');
            app.AfficherlimagebruiteButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherlimagebruiteButtonPushed, true);
            app.AfficherlimagebruiteButton.Text = 'Afficher l''image bruitée';
            app.TypedebruitEditField_2Label = uilabel(app.V3Tab);
            app.TypedebruitEditField_2Label.Text = 'Type de bruit';
            app.TypedebruitEditField = uieditfield(app.V3Tab, 'text');
            app.VariancedebruitEditField_2Label = uilabel(app.V3Tab);
            app.VariancedebruitEditField_2Label.Text = 'Variance de bruit';
            app.VariancedebruitEditField = uieditfield(app.V3Tab, 'numeric');
            app.SuppressiondebruitdoublecliquezpourchoisirListBoxLabel = uilabel(app.V3Tab);
            app.SuppressiondebruitdoublecliquezpourchoisirListBoxLabel.Text = 'Suppression de bruit (double-cliquez pour choisir)';
            app.SuppressiondebruitdoublecliquezpourchoisirListBox = uilistbox(app.V3Tab);
            app.SuppressiondebruitdoublecliquezpourchoisirListBox.Items = {'Median 3*3', 'Median 5*5', 'Median 7*7', 'Moyenneur 3*3', 'Moyenneur 5*5', 'Moyenneur 7*7'};
            app.SuppressiondebruitdoublecliquezpourchoisirListBox.DoubleClickedFcn = createCallbackFcn(app, @SuppressiondebruitdoublecliquezpourchoisirListBoxDoubleClicked, true);
            app.SuppressiondebruitdoublecliquezpourchoisirListBox.Value = 'Median 3*3';
            app.DetectiondecontourButtonGroup = uibuttongroup(app.V3Tab);
            app.DetectiondecontourButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DetectiondecontourButtonGroupSelectionChanged, true);
            app.DetectiondecontourButtonGroup.Title = 'Detection de contour';
            app.CannyButton = uiradiobutton(app.DetectiondecontourButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.CannyButton.Value = true;
            app.PrewittButton = uiradiobutton(app.DetectiondecontourButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uiradiobutton(app.DetectiondecontourButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uiradiobutton(app.DetectiondecontourButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.msgImgImporter_3 = uieditfield(app.V3Tab, 'text');
            app.SaveButton_3 = uibutton(app.V3Tab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Icon = fullfile(pathToMLAPP, 'saveIcon.png');
            app.SaveButton_3.Text = '';
            app.FentredoittreimpairEditFieldLabel = uilabel(app.V3Tab);
            app.FentredoittreimpairEditFieldLabel.Text = 'Fenêtre(doit être impair)';
            app.FentredoittreimpairEditField = uieditfield(app.V3Tab, 'numeric');
            app.FiltredordrekLabel = uilabel(app.V3Tab);
            app.FiltredordrekLabel.Text = 'Filtre d''ordre k';
            app.OrdredufiltreEditFieldLabel = uilabel(app.V3Tab);
            app.OrdredufiltreEditFieldLabel.Text = 'Ordre du filtre';
            app.OrdredufiltreEditField = uieditfield(app.V3Tab, 'numeric');
            app.AppliquerButton = uibutton(app.V3Tab, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_projet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end